classdef Copy_of_toyLF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        apertureSliderLabel    matlab.ui.control.Label
        apertureSlider         matlab.ui.control.Slider
        focalplaneSliderLabel  matlab.ui.control.Label
        focalplaneSlider       matlab.ui.control.Slider
        cameraXSliderLabel     matlab.ui.control.Label
        cameraXSlider          matlab.ui.control.Slider
        cameraYLabel           matlab.ui.control.Label
        cameraYSlider          matlab.ui.control.Slider
        cameraZSliderLabel     matlab.ui.control.Label
        cameraZSlider          matlab.ui.control.Slider
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%  x-y 轴位于光轴上，即uv-st平面中心
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % st平面
        img_width = 320;
        img_height = 240;
        imgs = zeros(16, 16, 240, 320, 3);  % 16*16=u,v  320 = s, 240 = t
        st_z = 0;
        color_chanel = 3;
        
        % uv平面/微透镜阵列
        uv_z = 1;
        aperture = 2; % 默认光圈
        
        % 虚拟相机
        camera_x = 0; % x位于相机中心
        camera_y = 0; % y位于相机中心
        camera_z = 1;
        camera_width = 320; 
        camera_height = 240;
        focal_plane_z = 0; % 焦平面
    end
    
    methods (Access = private)
        
        function img = img_show(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% 根据光圈、focal plane、相机位置、插值方式， 计算输出图像
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            img = zeros(app.camera_height, app.camera_width, app.color_chanel);
            
            for c_y = 1:app.camera_height
                for c_x = 1:app.camera_width
                    % 遍历相机焦平面的每个像素
                    
                    % 1.计算相机像素在空间中的实际坐标f,g
                    [f,g] = app.camera_pixel_location(c_y, c_x);
                    
                    % 2.计算从相机到像素坐标的光线在uv-st平面的实际坐标
                    % 计算(camera_x, camera_y, camera_z) 与 (f, g, focal_plane_z) 在 z=0, z=1的解
                    % 两点式 x-x1 / x2 - x1 == y-y1 / y2-y1 == z-z1 / z2-z1
                    % x1 = app.camera_x;
                    % y1 = app.camera_y;
                    % z1 = app.camera_z;
                    % x2 = f;
                    % y2 = g;
                    % z2 = app.focal_plane_z;
                    
                    % 令z=1，计算u,v
                    t = (1 - app.camera_z)/(app.focal_plane_z-app.camera_z);
                    u = app.camera_x + (f-app.camera_x)*t;
                    v = app.camera_y + (g-app.camera_y)*t;
                    
                    % 令z=0，计算s,t
                    t = -app.camera_z / (app.focal_plane_z-app.camera_z);
                    s = app.camera_x + (f-app.camera_x) * t;
                    t = app.camera_y + (g-app.camera_y) * t;
                    
                    
                    % 3.获取u,v周围通过 aperture*aperture 矩形的真实光线的u,v(矩阵坐标)

%                     aperture = round(app.aperture/2);
%                     us = zeros(1, 4 * aperture * aperture); % 0 表示坐标不在透镜阵列内
%                     vs = zeros(1, 4 * aperture * aperture);
%                     [y, x] = app.lens_location_reverse(u, v);
%                     x = floor(x);
%                     y = floor(y);
%                     cnt = 1;
%                     for  j = y+1-aperture:y+aperture
%                         for i = x+1-aperture:x+aperture
%                             if (i > 0 & i <=16) & (j > 0 & j <= 16) & (sqrt((i-x)^2+(j-y)^2)<=aperture)
%                                 vs(cnt) = j;
%                                 us(cnt) = i;
%                             end
%                             cnt = cnt + 1;
%                         end
%                     end

                    [y, x] = app.lens_location_reverse(u, v);
                    xx = floor(x);
                    yy = floor(y);
                    if xx <= 1 || yy <=1
                        continue
                    end
                    us = [xx, xx+1; xx, xx+1];
                    vs = [yy, yy; yy+1, yy+1];
                    
                    % 4.使用sparity计算s,t
%                     ss = zeros(1, 4 * aperture * aperture); % ss,ts为相对位置
%                     ts = zeros(1, 4 * aperture * aperture); % 0表示光线不在像素阵列内
%                     ds = 160.5 + s - u;
%                     dt = 120.5 - (t - v);
% 
%                     disparity = app.focal_plane_z / (app.focal_plane_z - 1);
%                     for i = 1:length(us)
%                         if us(i) ~= 0
%                             ss(i) = round(ds-app.img_width*disparity*(us(i)-x));
%                             ts(i) = round(dt+app.img_height*(4/3)*disparity*(vs(i)-y));    
%                         end
%                     end    
                    ds = round(160.5 + s - u);
                    dt = round(120.5 - t + v);
                    if (ds < 1 || ds >320) || (dt < 1 || dt > 240)
                        continue
                    end
                    % 5.进行Gaussian权重函数混合像素
                    
                    % 验证st合法性
%                     pixel = zeros(length(us), app.color_chanel);
%                     for i = 1:length(ss)
%                         if (ss(i) > 0 && ss(i) <= app.img_width) && (ts(i) > 0 && ts(i) <= app.img_height)
%                             pixel(i, :) = reshape(app.imgs(vs(i), us(i), ts(i), ss(i), :), 1, app.color_chanel);
%                         end
%                     end
                    pixel = zeros(2,2,3);
                    
                    for i = 1:2
                        for j = 1:2
                            pixel(i,j,:) = reshape(app.imgs(i,j,dt,ds,:), 1,1,3);
                        end
                    end
                    
                    % 使用高斯权重函数插值
%                     pixel = reshape(pixel, 2*aperture, 2*aperture, app.color_chanel);
%                     weight_x = normpdf(1:2*aperture, x-floor(x)+app.aperture/2, 2*app.aperture);
%                     weight_y = normpdf(1:2*aperture, y-floor(y)+app.aperture/2, 2*app.aperture);
%                     for c = 1:app.color_chanel
%                         img(c_y, c_x, c) =  weight_y * pixel(:,:,c) * weight_x';
%                     end

                    for c =1:3
                        img(c_y,c_x,c) = [1-y+floor(y), y-floor(y)] * pixel(:,:,c) * [1-x+floor(x);x-floor(x)];
                    end
                end
            end

            imshow(img, 'Parent',app.UIAxes);

        end
        
        function [] = init_ui(app)
            % 初始化ui参数
            app.apertureSlider.Value = app.aperture; % 初始camera 阵列的光圈
            app.cameraXSlider.Value = app.camera_x; % 相机x 
            app.cameraYSlider.Value = app.camera_y; % 相机y
            app.cameraZSlider.Value = app.camera_z; % 相机z
        end
        
        function [] = load_imgs(app)
            % 读取图片
            img_path_list = dir('*.bmp');
            for j = 1:16
                for i=1:16
                    app.imgs(i, j, :, :, :) = rescale( ...
                        imread(string(img_path_list((i-1)*16+j).name)), ...
                        0, 1); 
                end
            end
        end

        function [f,g] = camera_pixel_location(app, c_y, c_x)
            % 将240*320的矩阵坐标转换为实际坐标
            f = app.camera_x + c_x-160.5;
            g = app.camera_y + 120.5-c_y;
        end
        
        function [i,j] = lens_location_reverse(app, u, v)
            % 将透镜的实际坐标转换为16*16的矩阵坐标
            i = 8.5 - v / app.img_height;
            j = u / app.img_width + 8.5;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init_ui(); % 使用参数默认值初始化滚动条默认值
            app.load_imgs(); % 读取256张bmp图片
            app.img_show(); % 输出图像
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {327, 327};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {199, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: apertureSlider
        function apertureSliderValueChanged(app, event)
            % 改变光圈
            app.aperture = app.apertureSlider.Value;

            % 刷新图像
            app.img_show();
        end

        % Value changed function: focalplaneSlider
        function focalplaneSliderValueChanged(app, event)
            % 改变焦距
            app.focal_plane_z = app.focalplaneSlider.Value;

            % 刷新图像
            app.img_show();
        end

        % Value changed function: cameraXSlider
        function cameraXSliderValueChanged(app, event)
            % 改变相机x
            app.camera_x = app.cameraXSlider.Value;
            
            % 刷新图像
            app.img_show();
        end

        % Value changed function: cameraYSlider
        function cameraYSliderValueChanged(app, event)
            % 改变相机y
            app.camera_y = app.cameraYSlider.Value;
            
            % 刷新图像
            app.img_show();
        end

        % Value changed function: cameraZSlider
        function cameraZSliderValueChanged(app, event)
            % 改变相机z
            app.camera_z = app.cameraZSlider.Value;
            
            % 刷新图像
            app.img_show();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.apertureSliderLabel = uilabel(app.LeftPanel);
            app.apertureSliderLabel.Text = 'aperture';
            app.apertureSlider = uislider(app.LeftPanel);
            app.apertureSlider.Limits = [2 16];
            app.apertureSlider.MajorTicks = [2 4 6 8 10 12 14 16];
            app.apertureSlider.ValueChangedFcn = createCallbackFcn(app, @apertureSliderValueChanged, true);
            app.apertureSlider.MinorTicks = [];
            app.apertureSlider.Tooltip = {''};
            app.apertureSlider.Value = 2;
            app.focalplaneSliderLabel = uilabel(app.LeftPanel);
            app.focalplaneSliderLabel.Text = 'focal plane';
            app.focalplaneSlider = uislider(app.LeftPanel);
            app.focalplaneSlider.Limits = [0.01 0.03];
            app.focalplaneSlider.ValueChangedFcn = createCallbackFcn(app, @focalplaneSliderValueChanged, true);
            app.focalplaneSlider.MinorTicks = [];
            app.focalplaneSlider.Value = 0.03;
            app.cameraXSliderLabel = uilabel(app.LeftPanel);
            app.cameraXSliderLabel.Text = 'camera X';
            app.cameraXSlider = uislider(app.LeftPanel);
            app.cameraXSlider.Limits = [-5000 5000];
            app.cameraXSlider.ValueChangedFcn = createCallbackFcn(app, @cameraXSliderValueChanged, true);
            app.cameraXSlider.MinorTicks = [];
            app.cameraYLabel = uilabel(app.LeftPanel);
            app.cameraYLabel.Text = 'camera Y';
            app.cameraYSlider = uislider(app.LeftPanel);
            app.cameraYSlider.Limits = [-5000 5000];
            app.cameraYSlider.ValueChangedFcn = createCallbackFcn(app, @cameraYSliderValueChanged, true);
            app.cameraYSlider.MinorTicks = [];
            app.cameraZSliderLabel = uilabel(app.LeftPanel);
            app.cameraZSliderLabel.Text = 'camera Z';
            app.cameraZSlider = uislider(app.LeftPanel);
            app.cameraZSlider.Limits = [0 3];
            app.cameraZSlider.ValueChangedFcn = createCallbackFcn(app, @cameraZSliderValueChanged, true);
            app.cameraZSlider.MinorTicks = [];
            app.cameraZSlider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_of_toyLF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end