classdef lf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        apertureSliderLabel    matlab.ui.control.Label
        apertureSlider         matlab.ui.control.Slider
        focalplaneSliderLabel  matlab.ui.control.Label
        focalplaneSlider       matlab.ui.control.Slider
        cameraXSliderLabel     matlab.ui.control.Label
        cameraXSlider          matlab.ui.control.Slider
        cameraYLabel           matlab.ui.control.Label
        cameraYSlider          matlab.ui.control.Slider
        cameraZSliderLabel     matlab.ui.control.Label
        cameraZSlider          matlab.ui.control.Slider
        RightPanel             matlab.ui.container.Panel
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%  x-y 轴位于光轴上，即uv-st平面中心
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        % st平面
        img_width = 320;
        img_height = 240;
        imgs = zeros(16, 16, 240, 320, 3);  % 16*16=u,v  320 = s, 240 = t
        st_z = 0;
        color_chanel = 3;
        
        % uv平面/微透镜阵列
        uv_z = 1;
        aperture = 2; % 默认光圈
        
        % 虚拟相机
        camera_x = 0; % x位于相机中心
        camera_y = 0; % y位于相机中心
        camera_z = 2;
        camera_width = 320; 
        camera_height = 240;
        focal_plane_z = 0.97; % 虚拟相机的焦距(焦平面与虚拟相机的相对距离)
    end
    
    methods (Access = private)
        
        function img = img_show(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% 根据光圈、focal plane、相机位置、插值方式， 计算输出图像
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            img = zeros(app.camera_height, app.camera_width, app.color_chanel);
            
            for c_y = 1:app.camera_height
                for c_x = 1:app.camera_width
                    % 遍历相机焦平面的每个像素
                    
                    % 1.计算相机像素在空间中的实际坐标f,g
                    [f,g] = app.camera_pixel_location(c_y, c_x);
                    
                    % 2.计算从相机到像素坐标的光线在uv-st平面的实际坐标
                    % 计算(camera_x, camera_y, camera_z) 与 (f, g, focal_plane_z) 在 z=0, z=1的解
                    % 两点式 x-x1 / x2 - x1 == y-y1 / y2-y1 == z-z1 / z2-z1
                    x1 = app.camera_x;
                    y1 = app.camera_y;
                    z1 = app.camera_z;
                    
                    x2 = f;
                    y2 = g;
                    z2 = z1 - app.focal_plane_z;
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % 将 camera_z 与 focal_z分开
                    % 令z=1，计算u,v
                    t = (1 - z1)/(z2-z1);
                    u = x1 + (x2-x1)*t;
                    v = y1 + (y2-y1)*t;
                    
                    % 令z=0，计算s,t
                    t = (0-z1) / (z2-z1);
                    s = x1 + (x2-x1) * t;
                    t = y1 + (y2-y1) * t;
                    
                    
                    % 3.获取u,v周围通过 aperture*aperture 矩形的真实光线的u,v(矩阵坐标)

                    aperture = round(app.aperture/2);
                    us = zeros(1, 4 * aperture * aperture); % 0 表示坐标不在透镜阵列内
                    vs = zeros(1, 4 * aperture * aperture);
                    [y, x] = app.lens_location_reverse(u, v);
                    top_left = [floor(y), floor(x)];
                    cnt = 1;
                    for  j = top_left(2)+1-aperture:top_left(2)+aperture
                        for i = top_left(1)+1-aperture:top_left(1)+aperture
                            if (i > 0 & i <=16) & (j > 0 & j <= 16)
                                vs(cnt) = j;
                                us(cnt) = i;
                            end
                            cnt = cnt + 1;
                        end
                    end

                    % 4.使用sparity计算s,t
                    ss = zeros(1, 4 * aperture * aperture); % ss,ts为相对位置
                    ts = zeros(1, 4 * aperture * aperture); % 0表示光线不在像素阵列内
                    ds = 160.5 + s - u;
                    dt = 120.5 - (t - v);

                    disparity =  (app.focal_plane_z - 1) / app.focal_plane_z;
                    for i = 1:length(us)
                        if us(i) ~= 0
                            ss(i) = round(ds-app.img_width*disparity*(us(i)-x));
                            ts(i) = round(dt+app.img_height*disparity*(vs(i)-y));    
                        end
                    end    

                    
                    % 5.进行四线性插值\权重函数得到真正的像素
                    
                    % 验证st合法性
                    pixel = zeros(length(us), app.color_chanel);
                    for i = 1:length(ss)
                        if (ss(i) > 0 && ss(i) <= app.img_width) && (ts(i) > 0 && ts(i) <= app.img_height)
                            pixel(i, :) = reshape(app.imgs(vs(i), us(i), ts(i), ss(i), :), 1, app.color_chanel);
                        end
                    end
                    
                    % 使用高斯权重函数插值
                    pixel = reshape(pixel, 2*aperture, 2*aperture, app.color_chanel);
                    weight_x = normpdf(1:2*aperture, x-floor(x)+aperture, 0.5);
                    weight_y = normpdf(1:2*aperture, y-floor(y)+aperture, 0.5);
                    for c = 1:app.color_chanel
                        pixel_h = pixel(:,:,c) * weight_x';
                        pixel_v = weight_y * pixel_h;  
                        img(c_y, c_x, c) = pixel_v; 
                    end
                    
                    % 使用四线性插值,光圈必须为2
%                     pixel1_h = (x-us(1)) .* pixel(2,:) + (us(2)-x) .* pixel(1,:);
%                     pixel2_h = (x-us(3)) .* pixel(4,:) + (us(4)-x) .* pixel(3,:);
%                     pixel_v = (y-vs(1)) .* pixel2_h + (vs(3)-y) .* pixel1_h;
%                     img(c_y, c_x, :) = pixel_v; 
                        
                    
                end
            end
        end
        
        function [] = init_ui(app)
            % 初始化ui参数
            app.apertureSlider.Value = app.aperture; % 初始camera 阵列的光圈
            app.focalplaneSlider.Value = app.focal_plane_z; % 初始 focal plane
            app.cameraXSlider.Value = app.camera_x; % 相机x 
            app.cameraYSlider.Value = app.camera_y; % 相机y
            app.cameraZSlider.Value = app.camera_z; % 相机z
        end
        
        function [] = load_imgs(app)
            % 读取图片
            img_path_list = dir('*.bmp');
            for i = 1:16
                for j=1:16
                    app.imgs(i, j, :, :, :) = rescale( ...
                        imread(string(img_path_list((i-1)*16+j).name)), ...
                        0, 1); 
                end
            end
        end
        
        function [] = refresh_screen(app)
            % 生成新的图像并输出至屏幕
            img = app.img_show(); 
            imshow(img, 'Parent',app.UIAxes);
        end
        
        function [f,g] = camera_pixel_location(app, c_y, c_x)
            % 将240*320的矩阵坐标转换为实际坐标
            f = app.camera_x + c_x-160.5;
            g = app.camera_y + 120.5-c_y;
        end
        
        function [i,j] = lens_location_reverse(app, u, v)
            % 将透镜的实际坐标转换为16*16的矩阵坐标
            i = 8.5 - v / app.img_height;
            j = u / app.img_width + 8.5;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.init_ui(); % 使用参数默认值初始化滚动条默认值
            app.load_imgs(); % 读取256张bmp图片
            app.refresh_screen(); % 输出图像
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {665, 665};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: apertureSlider
        function apertureSliderValueChanged(app, event)
            % 改变光圈
            app.aperture = app.apertureSlider.Value;

            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: focalplaneSlider
        function focalplaneSliderValueChanged(app, event)
            % 改变焦距
            app.focal_plane_z = app.focalplaneSlider.Value;

            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraXSlider
        function cameraXSliderValueChanged(app, event)
            % 改变相机x
            app.camera_x = app.cameraXSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraYSlider
        function cameraYSliderValueChanged(app, event)
            % 改变相机y
            app.camera_y = app.cameraYSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end

        % Value changed function: cameraZSlider
        function cameraZSliderValueChanged(app, event)
            % 改变相机z
            app.camera_z = app.cameraZSlider.Value;
            
            % 刷新图像
            app.refresh_screen();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.apertureSliderLabel = uilabel(app.LeftPanel);
            app.apertureSliderLabel.Text = 'aperture';
            app.apertureSlider = uislider(app.LeftPanel);
            app.apertureSlider.Limits = [2 16];
            app.apertureSlider.MajorTicks = [2 4 6 8 10 12 14 16];
            app.apertureSlider.ValueChangedFcn = createCallbackFcn(app, @apertureSliderValueChanged, true);
            app.apertureSlider.MinorTicks = [];
            app.apertureSlider.Tooltip = {''};
            app.apertureSlider.Value = 2;
            app.focalplaneSliderLabel = uilabel(app.LeftPanel);
            app.focalplaneSliderLabel.Text = 'focal plane';
            app.focalplaneSlider = uislider(app.LeftPanel);
            app.focalplaneSlider.Limits = [0.96 1];
            app.focalplaneSlider.ValueChangedFcn = createCallbackFcn(app, @focalplaneSliderValueChanged, true);
            app.focalplaneSlider.MinorTicks = [];
            app.focalplaneSlider.Value = 0.96;
            app.cameraXSliderLabel = uilabel(app.LeftPanel);
            app.cameraXSliderLabel.Text = 'camera X';
            app.cameraXSlider = uislider(app.LeftPanel);
            app.cameraXSlider.Limits = [-2500 2500];
            app.cameraXSlider.ValueChangedFcn = createCallbackFcn(app, @cameraXSliderValueChanged, true);
            app.cameraXSlider.MinorTicks = [];
            app.cameraYLabel = uilabel(app.LeftPanel);
            app.cameraYLabel.Text = 'camera Y';
            app.cameraYSlider = uislider(app.LeftPanel);
            app.cameraYSlider.Limits = [-2500 2500];
            app.cameraYSlider.ValueChangedFcn = createCallbackFcn(app, @cameraYSliderValueChanged, true);
            app.cameraYSlider.MinorTicks = [];
            app.cameraZSliderLabel = uilabel(app.LeftPanel);
            app.cameraZSliderLabel.Text = 'camera Z';
            app.cameraZSlider = uislider(app.LeftPanel);
            app.cameraZSlider.Limits = [0 10];
            app.cameraZSlider.ValueChangedFcn = createCallbackFcn(app, @cameraZSliderValueChanged, true);
            app.cameraZSlider.MinorTicks = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end